classdef gui_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SelectResultsTab              matlab.ui.container.Tab
        AvailableResultsListBoxLabel  matlab.ui.control.Label
        AvailableResultsListBox       matlab.ui.control.ListBox
        AddResultButton               matlab.ui.control.Button
        SelectedResultsListBoxLabel   matlab.ui.control.Label
        SelectedResultsListBox        matlab.ui.control.ListBox
        MountLocationsButton          matlab.ui.control.Button
        RefreshButton                 matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        MountStiffnessButton          matlab.ui.control.Button
        KEDButton                     matlab.ui.control.Button
        FrequencyButton               matlab.ui.control.Button
        MountForcesButton             matlab.ui.control.Button
        CGDisplacementsButton         matlab.ui.control.Button
        StaticDeflectionsButton       matlab.ui.control.Button
        ResultsLabel                  matlab.ui.control.Label
        CloseButton                   matlab.ui.control.Button
    end


    properties (Access = private)
         % Description
         
         
    end

    properties (Access = public)
        n=0;%number of selected results
        items % cell array of selected items
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;        close all;    clear;
            mat_files=dir('*.mat'); %finding .mat files
            k=size(mat_files)
            for i=1:k(1)
            
                app.AvailableResultsListBox.Items(i)={mat_files(i).name}
                
            end
            
        end

        % Button pushed function: MountLocationsButton
        function MountLocationsButtonPushed(app, event)
            figure(1);    hold on %figure of mount locations
            
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
               
               plot_legend=[plot_legend;app.items{i}(1:end-4)];
            %                load(app.items{i});
            %                posPlotter([x_opt1_f(1:3),x_opt1_f(4:6),x_opt1_f(7:9)],rand(1,3))
                
            end
            [q_TRA,~] = TRA_finder(M(4:6,4:6),[0;1;0]); % finding TRA axis
            q_TRA = [0;0;0;q_TRA];
            TRA_plotter(q_TRA);


            legend(plot_legend);
        end

        % Value changed function: SelectedResultsListBox
        function SelectedResultsListBoxValueChanged(app, event)
            
            app.items = app.SelectedResultsListBox.Items;
            
        end

        % Value changed function: AvailableResultsListBox
        function AvailableResultsListBoxValueChanged(app, event)


        end

        % Button pushed function: AddResultButton
        function AddResultButtonPushed(app, event)
            app.n=app.n+1;
            app.SelectedResultsListBox.Items(app.n)= {app.AvailableResultsListBox.Value};
            app.items = app.SelectedResultsListBox.Items;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            mat_files=dir('SavedResults/*.mat'); %finding .mat files
            k=size(mat_files);
            for i=1:k(1)
            
                app.AvailableResultsListBox.Items(i)={mat_files(i).name};
                
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            app.SelectedResultsListBox.Items={''}
            
           app.n=0
        end

        % Button pushed function: MountStiffnessButton
        function MountStiffnessButtonPushed(app, event)
            figure(2);    hold on; %stiffness figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               stiffPlotter([ub_k_1';ub_k_2';ub_k_3'],[lb_k_1';lb_k_2';lb_k_3'],x_opt1_f);
                
            end
            
        end

        % Button pushed function: KEDButton
        function KEDButtonPushed(app, event)
            h=figure(3);    hold on; %KED figure
            k=size(app.items);
            
            for i=1:k(2)
              
               load(app.items{i});
               [K, ~] = stiff_cal(x_opt1_f,eta);
               KEF_opt = KEF_cal(K,M);
               KEDplotter(KEF_init,KEF_opt);
                
            end
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            figure(4);    hold on; %frequency figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               f_nat_opt = NF_Calculator(x_opt1_f,M);
               freqPlotter(f_nat_init,f_nat_opt,f_nat_lb,f_nat_ub);
                
            end
            
        end

        % Button pushed function: StaticDeflectionsButton
        function StaticDeflectionsButtonPushed(app, event)
            figure(5);    hold on; %static deflections figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               [K, ~] = stiff_cal(x_opt1_f,eta);
               stc_opt = abs((K/1.5)^(-1)*[0;0;M(1,1)*9.81;0;0;0]);
               st_def_plot(delta_s,stc_opt);
                
            end
        end

        % Button pushed function: MountForcesButton
        function MountForcesButtonPushed(app, event)
            figure(6);    hold on; %mount forces figure
            
            k=size(app.items);
            plot_legend='';
            for i=1:k(2)
              
               load(app.items{i});
               plot_legend=[plot_legend;app.items{i}(1:end-4)]
               
               Force_Plot(F_1_init,F_2_init,F_3_init,F_1_opt,F_2_opt,F_3_opt);
                
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectResultsTab = uitab(app.TabGroup);
            app.SelectResultsTab.Title = 'Select Results';
            app.AvailableResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.AvailableResultsListBoxLabel.Text = 'Available Results';
            app.AvailableResultsListBox = uilistbox(app.SelectResultsTab);
            app.AvailableResultsListBox.Items = {};
            app.AvailableResultsListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableResultsListBoxValueChanged, true);
            app.AvailableResultsListBox.Value = {};
            app.AddResultButton = uibutton(app.SelectResultsTab, 'push');
            app.AddResultButton.ButtonPushedFcn = createCallbackFcn(app, @AddResultButtonPushed, true);
            app.AddResultButton.Text = 'Add Result';
            app.SelectedResultsListBoxLabel = uilabel(app.SelectResultsTab);
            app.SelectedResultsListBoxLabel.Text = 'Selected Results';
            app.SelectedResultsListBox = uilistbox(app.SelectResultsTab);
            app.SelectedResultsListBox.Items = {};
            app.SelectedResultsListBox.ValueChangedFcn = createCallbackFcn(app, @SelectedResultsListBoxValueChanged, true);
            app.SelectedResultsListBox.Value = {};
            app.MountLocationsButton = uibutton(app.SelectResultsTab, 'push');
            app.MountLocationsButton.ButtonPushedFcn = createCallbackFcn(app, @MountLocationsButtonPushed, true);
            app.MountLocationsButton.Text = 'Mount Locations';
            app.RefreshButton = uibutton(app.SelectResultsTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.DeleteButton = uibutton(app.SelectResultsTab, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.MountStiffnessButton = uibutton(app.SelectResultsTab, 'push');
            app.MountStiffnessButton.ButtonPushedFcn = createCallbackFcn(app, @MountStiffnessButtonPushed, true);
            app.MountStiffnessButton.Text = 'Mount Stiffness';
            app.KEDButton = uibutton(app.SelectResultsTab, 'push');
            app.KEDButton.ButtonPushedFcn = createCallbackFcn(app, @KEDButtonPushed, true);
            app.KEDButton.Text = 'KED';
            app.FrequencyButton = uibutton(app.SelectResultsTab, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.MountForcesButton = uibutton(app.SelectResultsTab, 'push');
            app.MountForcesButton.ButtonPushedFcn = createCallbackFcn(app, @MountForcesButtonPushed, true);
            app.MountForcesButton.Text = 'Mount Forces';
            app.CGDisplacementsButton = uibutton(app.SelectResultsTab, 'push');
            app.CGDisplacementsButton.Text = 'CG Displacements';
            app.StaticDeflectionsButton = uibutton(app.SelectResultsTab, 'push');
            app.StaticDeflectionsButton.ButtonPushedFcn = createCallbackFcn(app, @StaticDeflectionsButtonPushed, true);
            app.StaticDeflectionsButton.Text = 'Static Deflections';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.Text = 'Close';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui_results

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end